classdef plot_power_dB < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RealTimePlotAppUIFigure  matlab.ui.Figure
    end

    
    properties (Access = public)
        StartPlotButton     matlab.ui.control.Button
        ChangeOffsetButton  matlab.ui.control.Button
        StartPrintButton    matlab.ui.control.Button
        UIAxes              matlab.ui.control.UIAxes
        Rf                  double = 300e+3;
        sensor_area         double = (2e-3)^2;     % m^2
        gain                double = 2;
        N_sensor_per_axis   double = 7;
        ads1115_to_dB
        line_colors
        
        % initialize Ploting var
        window_width_time   double = 20;     % sec
        window_height       double = [-8 8];
        plot_freq           double = 4; % plot every # data points
        WindowWidthField    matlab.ui.control.NumericEditField % Numeric edit field for window width
        WindowWidthLabel    matlab.ui.control.Label % Label for window width input
        UITextArea          matlab.ui.control.TextArea
        UpdateTimer         timer

        % data smoothing parameters
        sampleSize          double = 1;  % Define the size of the moving window
        dataBufferX
        dataBufferY
        smoothedDataX
        smoothedDataY
        
        % initialize serial port connection and figure
        baud_rate           double = 9600;
        total_sample_time   double = 3600;    % sec
        dataLogger
        it_count            double = 0;
    end
    
    properties (Access = private)
        t
        current_t
        current_v
        dBx
        dBy
        offsetX
        offsetY
        plotting            logical = false; % Plotting flag
        printdata           logical = false; % Plotting flag
        hPlot               matlab.graphics.chart.primitive.Line % Handle to plot
    end
    
    methods (Access = private)
        
        % Function to start real-time plotting
        function startPlotting(app)
            app.plotting = true;
            hold(app.UIAxes, 'on');
            grid(app.UIAxes, 'on');
            for k = 1:app.N_sensor_per_axis
                app.hPlot(k) = plot(app.UIAxes, 0,0,'-','LineWidth',1.2,'Color',app.line_colors(:,k));
            end
            for k = 1:app.N_sensor_per_axis
                app.hPlot(k+app.N_sensor_per_axis) = plot(app.UIAxes, 0,0,'-','LineWidth',1.2,'Color',app.line_colors(:,k+app.N_sensor_per_axis));
            end
            while app.plotting
                text = convertStringsToChars(readline(app.dataLogger));
                if size(text,2) > 0
                    app.it_count = app.it_count + 1;
                    ind = strfind(text,',');    % find index of comma
                    app.current_t = str2double(text(1:ind(1)-1))*1e-6;
                    for k = 1:app.N_sensor_per_axis*2
                        app.current_v(k) = min(str2double(text(ind(k)+1:ind(k+1)-1)),48848);
                    end
                    app.dBx = [app.dBx app.ads1115_to_dB(app.current_v(1:2:end),app.gain,app.Rf)];
                    app.dBy = [app.dBy app.ads1115_to_dB(app.current_v(2:2:end),app.gain,app.Rf)];
                    app.t = [app.t app.current_t];
                    
                    app.dataBufferX = app.dBx(:,max([2,end-app.sampleSize]):end);
                    app.dataBufferY = app.dBy(:,max([2,end-app.sampleSize]):end);
                    app.smoothedDataX(:,end+1) = mean(app.dataBufferX,2);
                    app.smoothedDataY(:,end+1) = mean(app.dataBufferY,2);        

                    if app.it_count >= app.plot_freq
                        hold(app.UIAxes, 'on');
                        for k = 1:app.N_sensor_per_axis
%                             app.hPlot(2*k-1) = plot(app.UIAxes, app.t(2:end), app.smoothedDataX(k,2:end)-app.offsetX(k),'-','LineWidth',1.2,'Color',app.line_colors(:,k));
%                             app.hPlot(2*k)   = plot(app.UIAxes, app.t(2:end), app.smoothedDataY(k,2:end)-app.offsetY(k),'-','LineWidth',1.2,'Color',app.line_colors(:,k+app.N_sensor_per_axis));
                            set(app.hPlot(k), 'XData', app.t(2:end), 'YData', app.smoothedDataX(k,2:end)-app.offsetX(k));
                            set(app.hPlot(k+app.N_sensor_per_axis),   'XData', app.t(2:end), 'YData', app.smoothedDataY(k,2:end)-app.offsetY(k));
                        end
                        app.UIAxes.XLim = [max(0,app.current_t-app.window_width_time), app.current_t+app.window_width_time*0.05];
                        app.UIAxes.YLim = app.window_height;
                        drawnow;
                        app.it_count = 0;
                        legend(app.UIAxes, 'x_1','x_2','x_3','x_4','x_5','x_6','x_7',...
                            'y_1','y_2','y_3','y_4','y_5','y_6','y_7',...
                            'Location','westoutside','NumColumns',1)
                        hold(app.UIAxes, 'off');
                    end

                    if app.printdata
                        fprintf('\n\nu: ');
                        fprintf('%.2f ',app.smoothedDataX(:,end)-app.offsetX);
                        fprintf('\nv: ');
                        fprintf('%.2f ',app.smoothedDataY(:,end)-app.offsetY);
                        app.printdata = false;
                    end
                end
            end
        end
        
        % Function to change the offset of the signal
        function changeOffset(app)
            app.offsetX = app.smoothedDataX(:,end); % Increase offset by 1
            app.offsetY = app.smoothedDataY(:,end); % Increase offset by 1
        end

        % Button pushed function: StartPlotButton
        function StartPlotButtonPushed(app, event)
            if app.plotting
                app.plotting = false;  % Stop the plotting
                app.StartPlotButton.Text = 'Start Plot';
            else
                app.StartPlotButton.Text = 'Stop Plot';
                startPlotting(app);  % Start plotting data
            end
        end
        
        function StartPrintButtonPushed(app, event)
            app.printdata = true;
        end

        % Button pushed function: ChangeOffsetButton
        function ChangeOffsetButtonPushed(app, event)
            changeOffset(app);  % Change the signal offset
        end

        % Callback to update the window width based on user input
        function updateWindowWidth(app, event)
            app.sampleSize = app.WindowWidthField.Value;  % Get the value from the text box
        end

                % Timer callback function to update the text area
        function updateTextArea(app, ~, ~)
            if exist('logfile.txt', 'file')
                % Read the contents of the log file
                fileID = fopen('logfile.txt', 'r');
                fileContents = fread(fileID, '*char')';
                fclose(fileID);
                
                % Update the text area with the contents
                app.UITextArea.Value = fileContents;

                % Scroll to the bottom of the text area
                app.UITextArea.Value = splitlines(app.UITextArea.Value);  % Ensure the content is split into lines
%                 app.UITextArea.ScrollTo(app.UITextArea.Value(end));       % Scroll to the last line
%                 scroll(app.UITextArea, 'bottom')
            end
        end

        function startLogging(app)
            % Clear the contents of the log file by opening it in write mode
            fileID = fopen('logfile.txt', 'w');
            fclose(fileID);  % Close the file to finalize clearing

            % Specify the log file name
            diary('logfile.txt');  % Start logging output to a file
            diary on;  % Turn on diary logging
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function name(app)
            app.ads1115_to_dB = @(signal,gain,R) 10*log10( 1e+6.*(4.096.*signal./(48848.*gain))./R );  % dB of lux, proportional to dB power
            app.line_colors = [linspace(0.15,0.75,app.N_sensor_per_axis);linspace(0.7,0.8,app.N_sensor_per_axis);linspace(0.85,0.25,app.N_sensor_per_axis)];
            app.line_colors = [app.line_colors [linspace(0.5,0.8,app.N_sensor_per_axis);linspace(0.25,0.15,app.N_sensor_per_axis);linspace(0.75,0.2,app.N_sensor_per_axis)]];
            app.dataBufferX = zeros(1, app.sampleSize);  % Pre-allocate buffer
            app.dataBufferY = zeros(1, app.sampleSize);  % Pre-allocate buffer
            app.smoothedDataX = [];  % Initialize an empty array to store smoothed data
            app.smoothedDataY = [];  % Initialize an empty array to store smoothed data
            app.dataLogger=serialport('COM5',app.baud_rate);
            app.current_t = 0;
            app.current_v = zeros(app.N_sensor_per_axis*2,1);
            app.dBx = zeros(app.N_sensor_per_axis,0);
            app.dBy = zeros(app.N_sensor_per_axis,0);
            app.t = [];
            app.offsetX = zeros(app.N_sensor_per_axis,1);     % Signal offset
            app.offsetY = zeros(app.N_sensor_per_axis,1);     % Signal offset

            % Create StartPlotButton
            app.StartPlotButton = uibutton(app.RealTimePlotAppUIFigure, 'push');
            app.StartPlotButton.Position = [100 500 150 40];
            app.StartPlotButton.Text = 'Start Plot';
            app.StartPlotButton.ButtonPushedFcn = createCallbackFcn(app, @StartPlotButtonPushed, true);

            % Create StartPrintButton
            app.StartPrintButton = uibutton(app.RealTimePlotAppUIFigure, 'push');
            app.StartPrintButton.Position = [500 500 150 40];
            app.StartPrintButton.Text = 'Print';
            app.StartPrintButton.ButtonPushedFcn = createCallbackFcn(app, @StartPrintButtonPushed, true);

            % Create ChangeOffsetButton
            app.ChangeOffsetButton = uibutton(app.RealTimePlotAppUIFigure, 'push');
            app.ChangeOffsetButton.Position = [300 500 150 40];
            app.ChangeOffsetButton.Text = 'Change Offset';
            app.ChangeOffsetButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeOffsetButtonPushed, true);

            % Create Label for sample size
            app.WindowWidthLabel = uilabel(app.RealTimePlotAppUIFigure);
            app.WindowWidthLabel.Position = [100 450 100 30];
            app.WindowWidthLabel.Text = 'Sample Size:';
            
            % Create Numeric Edit Field for sample size
            app.WindowWidthField = uieditfield(app.RealTimePlotAppUIFigure, 'numeric');
            app.WindowWidthField.Position = [200 450 100 30];
            app.WindowWidthField.Value = app.sampleSize;  % Set initial window width
            app.WindowWidthField.ValueChangedFcn = createCallbackFcn(app, @updateWindowWidth, true);  % Assign callback

            % Create UIAxes
            app.UIAxes = uiaxes(app.RealTimePlotAppUIFigure);
            app.UIAxes.Position = [20 130 600 300];
            app.UIAxes.XLim = [0 10];
            app.UIAxes.YLim = [-10 10];
            title(app.UIAxes, 'Phototransistor Data');
            xlabel(app.UIAxes, 'Time (sec)', 'FontSize', 14)
            ylabel(app.UIAxes, 'Illuminance (dB)', 'FontSize', 14)

            % Create UITextArea
            app.UITextArea = uitextarea(app.RealTimePlotAppUIFigure);
            app.UITextArea.Position = [20 20 400 90];
            app.UITextArea.Editable = 'off';  % Make it read-only
            app.UITextArea.FontSize = 14;
            
            % Start logging
            startLogging(app);

            % Create and start a timer to periodically update the UITextArea
            app.UpdateTimer = timer('ExecutionMode', 'fixedRate', 'Period', 1, ...
                                    'TimerFcn', @(~,~) updateTextArea(app));
            start(app.UpdateTimer);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RealTimePlotAppUIFigure = uifigure('Visible', 'off');
            app.RealTimePlotAppUIFigure.Name = 'Real-Time Plot App';
            % Show the figure after all components are created
            app.RealTimePlotAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = plot_power_dB

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RealTimePlotAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @name)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RealTimePlotAppUIFigure)
        end
    end
end