classdef shape_stress_reconstruction < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RealTimePlotAppUIFigure  matlab.ui.Figure
    end

    
    properties (Access = public)
        % optimization param
        N_coeff
        c_t = 4;
        tanh_c
        P_uv
        C
        C0
        L                   double = 14;
        dB_per_cm_fit
        options
        curve_coeff
        N_wg                double = 7;
        wg_loc
        tilt_amp            double = 1.5;
        N_mesh              double = 15;
        stress_pts          double = 0;
        stress_mag          double = 0;
        hPlot               matlab.graphics.chart.primitive.Line % Handle to plot

        % UI objects
        StartMeasureButton  matlab.ui.control.Button
        StartPlotButton     matlab.ui.control.Button
        ChangeOffsetButton  matlab.ui.control.Button
        StressSensingButton matlab.ui.control.Button
        UIAxes              matlab.ui.control.UIAxes
        UITextArea          matlab.ui.control.TextArea
        UpdateTimer         timer

        % initialize Ploting var
        Rf                  double = 300e+3;
        sensor_area         double = (2e-3)^2;     % m^2
        gain                double = 2;
        N_lines_per_axis    double = 7;
        ads1115_to_dB
        window_width        double = [-10 10];
        SampleSizeField    matlab.ui.control.NumericEditField % Numeric edit field for window width
        SampleSizeLabel    matlab.ui.control.Label % Label for window width input

        % data smoothing parameters
        sampleSize          double = 1;  % Define the size of the moving window
        smoothedData
        
        % initialize serial port connection
        comName = 'COM5';
        baud_rate           double = 9600;
        dataLogger
    end
    
    properties (Access = private)
        t
        current_t
        current_v
        dBx
        dBy
        offset
        lock_offset
        plotting            logical = false; % Plotting flag
        measuring           logical = true;  % Plotting flag
        stress_sensing      logical = false; % stress sensing flag
    end
    
    methods (Access = private)
        
        % Function to start real-time plotting
        function startMeasurement(app)
            app.measuring = true;

            while app.measuring
                
                text = get_latest_data(app);
                if size(text,2) > 0
                    ind = strfind(text,',');
%                     app.current_t = str2double(text(1:ind(1)-1))*1e-6;
                    for k = 1:app.N_lines_per_axis*2
                        app.current_v(k) = min(str2double(text(ind(k)+1:ind(k+1)-1)),48848);
                    end
                    
                    app.dBx = [app.dBx(:,max([1,end-app.sampleSize]):end) app.ads1115_to_dB(app.current_v(1:2:end),app.gain,app.Rf)];
                    app.dBy = [app.dBy(:,max([1,end-app.sampleSize]):end) app.ads1115_to_dB(app.current_v(2:2:end),app.gain,app.Rf)];
%                     app.t = [app.t app.current_t];
                    
                    app.smoothedData = [mean(app.dBx,2) mean(app.dBy,2)]' - app.offset' - app.lock_offset';
                    fprintf('\n\nu: ');
                    fprintf('%.2f ',app.smoothedData(1,:));
                    fprintf('\nv: ');
                    fprintf('%.2f ',app.smoothedData(2,:)');
                    if app.plotting
                        if ~app.stress_sensing
                            app.C = shape_optimizer(app.smoothedData,app.N_coeff,app.C0,7,app.wg_loc,app.L,app.dB_per_cm_fit,app.options,false,[]);
                            plot_curve_surf(app);
                        else
                            app.stress_pts = stress_optimizer(app.smoothedData,app.P_uv,app.C,app.wg_loc,false);
                            app.stress_mag = plot_stress(app);
                        end
                    end
                end
            end
        end
        
        % Function to get latest data from COM
        function txt = get_latest_data(app)
            flush(app.dataLogger, "input");
            readline(app.dataLogger);
            txt = convertStringsToChars(readline(app.dataLogger));
        end

        % Function to change the offset of the signal
        function zeroOffset(app)
            app.offset = app.offset + app.smoothedData';
        end
        
        % Button pushed function: StressSensingButton
        function StressSensingButtonPushed(app, event)
            if app.stress_sensing   % change from stress to shape sensing
                app.lock_offset = 0;
                app.stress_mag = 0;
                set(app.hPlot(end), 'XData', 100, 'YData', 100, 'ZData', 100, 'MarkerSize', 0.1);                app.stress_sensing = false;  % Stop the measuring
                app.StressSensingButton.Text = sprintf('Lock Shape and \nStart Stress Sensing');
                title(app.UIAxes, 'Optimized Sheet Shape');
            else                    % change from shape to stress sensing
                app.lock_offset = app.lock_offset + app.smoothedData';
                app.stress_sensing = true;  % Stop the measuring
                app.StressSensingButton.Text = sprintf('Zero Stress and \nStart Shape Sensing');
                title(app.UIAxes, 'Sheet Stress Distribution');
            end
        end

        % Button pushed function: StartMeasureButton
        function StartMeasureButtonPushed(app, event)
            if app.measuring
                app.measuring = false;  % Stop the measuring
                app.StartMeasureButton.Text = 'Start Measure';
            else
                app.StartMeasureButton.Text = 'Stop Measure';
                startMeasurement(app);  % Start measuring data from Arduino
            end
        end

        % Button pushed function: StartPlotButton
        function StartPlotButtonPushed(app, event)
            if app.plotting
                app.plotting = false;  % Stop the plotting
                app.StartPlotButton.Text = 'Start Plot';
            else
                app.plotting = true;  % Start the plotting
                zeroOffset(app);  % zero all signals
                app.StartPlotButton.Text = 'Stop Plot';
            end
        end

        % Button pushed function: ChangeOffsetButton
        function ChangeOffsetButtonPushed(app, event)
            zeroOffset(app);  % Change the signal offset
        end

        % Callback to update the window width based on user input
        function updateWindowWidth(app, event)
            app.sampleSize = app.SampleSizeField.Value;  % Get the value from the text box
        end
        
        % Method to initialize the diary and start logging
        function startLogging(app)
            % Clear the contents of the log file by opening it in write mode
            fileID = fopen('logfile.txt', 'w');
            fclose(fileID);  % Close the file to finalize clearing

            % Specify the log file name
            diary('logfile.txt');  % Start logging output to a file
            diary on;  % Turn on diary logging
        end
        
        % Timer callback function to update the text area
        function updateTextArea(app, ~, ~)
            if exist('logfile.txt', 'file')
                % Read the contents of the log file
                fileID = fopen('logfile.txt', 'r');
                fileContents = fread(fileID, '*char')';
                fclose(fileID);
                
                % Update the text area with the contents
                app.UITextArea.Value = fileContents;

                % Scroll to the bottom of the text area
                app.UITextArea.Value = splitlines(app.UITextArea.Value);  % Ensure the content is split into lines
%                 app.UITextArea.ScrollTo(app.UITextArea.Value(end));       % Scroll to the last line
                scroll(app.UITextArea, 'bottom')
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function name(app)
            app.ads1115_to_dB = @(signal,gain,R) 10*log10( 1e+6.*(4.096.*signal./(48848.*gain))./R );  % dB of lux, proportional to dB power
            app.smoothedData = zeros(2,app.N_lines_per_axis);  % Initialize an empty array to store smoothed data
            app.dataLogger = serialport(app.comName,app.baud_rate);
%             app.current_t = 0;
            app.current_v = zeros(app.N_lines_per_axis*2,1);
            app.dBx = zeros(app.N_lines_per_axis,0);
            app.dBy = zeros(app.N_lines_per_axis,0);
%             app.t = [];
            app.offset = zeros(app.N_lines_per_axis,2);     % Signal offset
            app.lock_offset = 0;     % Signal offset when locking shape
            
            app.N_coeff = 15;
            app.tanh_c = @(x) tanh(app.c_t*x-0.5*app.c_t)+1;
%             app.P_uv = @(A,u,v) A*[u; v; ...
%                    app.tanh_c(u).*app.tanh_c(v); app.tanh_c(u).*app.tanh_c(-v); app.tanh_c(-u).*app.tanh_c(v); app.tanh_c(-u).*app.tanh_c(-v);...
%                    app.tanh_c(u); app.tanh_c(-u); app.tanh_c(v); app.tanh_c(-v)];
%             app.P_uv = @(A,u,v) A*[u; v; u.^2; u.*v; v.^2; u.^3; u.^2.*v; u.*v.^2; v.^3];
            app.P_uv = @(A,u,v) A*[u; v; u.^2; u.*v; v.^2; u.^3; u.^2.*v; u.*v.^2; v.^3;...
                                   u.^2.*v.^2; u.^3.*v; u.*v.^3; u.^3.*v.^2; u.^2.*v.^3; u.^3.*v.^3];

            app.C = zeros(3,app.N_coeff);app.C(1,1) = app.L-0.2; app.C(2,2) = app.L-0.2;
            app.C0 = app.C;
            app.wg_loc = zeros(4,2*app.N_wg);   % first 2 row = u1,v1, last 2 row = u2,v2, wg 1-7 and then 8-14
            app.wg_loc(1,1:app.N_wg) = -0.5; app.wg_loc(3,1:app.N_wg) = 0.5;
            app.wg_loc([2 4],1:app.N_wg) = [linspace(-0.45,0.45,app.N_wg);linspace(-0.45,0.45,app.N_wg)];
            app.wg_loc(2,1+app.N_wg:end) = -0.5; app.wg_loc(4,1+app.N_wg:end) = 0.5;
            app.wg_loc([1 3],1+app.N_wg:end) = [linspace(-0.45,0.45,app.N_wg);linspace(-0.45,0.45,app.N_wg)];
            app.wg_loc(2,[2 4 6]) = app.wg_loc(2,[2 4 6])-0.5*[0.1 0.1 0.1]*app.tilt_amp;
            app.wg_loc(4,[2 4 6]) = app.wg_loc(4,[2 4 6])+0.5*[0.1 0.1 0.1]*app.tilt_amp;
            app.wg_loc(1,[2 4 6]+7) = app.wg_loc(1,[2 4 6]+7)+0.5*[0.1 0.1 0.1]*app.tilt_amp;
            app.wg_loc(3,[2 4 6]+7) = app.wg_loc(3,[2 4 6]+7)-0.5*[0.1 0.1 0.1]*app.tilt_amp;
            app.wg_loc(2,[1 3 5 7]) = app.wg_loc(2,[1 3 5 7])+0.5*[0.1 0.1 0.1 0.1]*app.tilt_amp;
            app.wg_loc(4,[1 3 5 7]) = app.wg_loc(4,[1 3 5 7])-0.5*[0.1 0.1 0.1 0.1]*app.tilt_amp;
            app.wg_loc(1,[1 3 5 7]+7) = app.wg_loc(1,[1 3 5 7]+7)-0.5*[0.1 0.1 0.1 0.1]*app.tilt_amp;
            app.wg_loc(3,[1 3 5 7]+7) = app.wg_loc(3,[1 3 5 7]+7)+0.5*[0.1 0.1 0.1 0.1]*app.tilt_amp;
            app.wg_loc(:,8:end) = flip(app.wg_loc(:,8:end),2);

            app.curve_coeff = load('sheet_calibration.mat','curve_coeff');
            app.curve_coeff = app.curve_coeff.curve_coeff;
            app.dB_per_cm_fit = @(kappa,n) app.curve_coeff(n,1)*kappa.^3 + app.curve_coeff(n,2)*kappa.^2 + app.curve_coeff(n,3)*kappa;
%             app.dB_per_cm_fit = @(kappa,n) app.curve_coeff(n,3)*kappa;
            app.options = optimoptions('fmincon','Algorithm','sqp','EnableFeasibilityMode',true,'SubproblemAlgorithm', 'cg',...
                'MaxIterations',20,'SpecifyObjectiveGradient',false,'SpecifyConstraintGradient',true,...
                'ConstraintTolerance',5,'FunctionTolerance',1e-1,'StepTolerance',1e-1,'Display','none','MaxFunctionEvaluations',1e+3,...
                'ObjectiveLimit',1,'OptimalityTolerance',1e-1);
            
            % Create StressSensingButton
            app.StressSensingButton = uibutton(app.RealTimePlotAppUIFigure, 'push');
            app.StressSensingButton.Position = [500 125 130 50];
            app.StressSensingButton.Text = sprintf('Lock Shape and \nStart Stress Sensing');
            app.StressSensingButton.ButtonPushedFcn = createCallbackFcn(app, @StressSensingButtonPushed, true);

            % Create StartMeasureButton
            app.StartMeasureButton = uibutton(app.RealTimePlotAppUIFigure, 'push');
            app.StartMeasureButton.Position = [50 130 100 40];
            app.StartMeasureButton.Text = 'Stop Measure';
            app.StartMeasureButton.ButtonPushedFcn = createCallbackFcn(app, @StartMeasureButtonPushed, true);

            % Create StartPlotButton
            app.StartPlotButton = uibutton(app.RealTimePlotAppUIFigure, 'push');
            app.StartPlotButton.Position = [200 130 100 40];
            app.StartPlotButton.Text = 'Start Plot';
            app.StartPlotButton.ButtonPushedFcn = createCallbackFcn(app, @StartPlotButtonPushed, true);

            % Create ChangeOffsetButton
            app.ChangeOffsetButton = uibutton(app.RealTimePlotAppUIFigure, 'push');
            app.ChangeOffsetButton.Position = [350 130 100 40];
            app.ChangeOffsetButton.Text = 'Reset Shape';
            app.ChangeOffsetButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeOffsetButtonPushed, true);

            % Create Label for sample size
            app.SampleSizeLabel = uilabel(app.RealTimePlotAppUIFigure);
            app.SampleSizeLabel.Position = [500 50 100 30];
            app.SampleSizeLabel.Text = 'Sample Size:';
            
            % Create Numeric Edit Field for sample size
            app.SampleSizeField = uieditfield(app.RealTimePlotAppUIFigure, 'numeric');
            app.SampleSizeField.Position = [580 50 30 30];
            app.SampleSizeField.Value = app.sampleSize;  % Set initial window width
            app.SampleSizeField.ValueChangedFcn = createCallbackFcn(app, @updateWindowWidth, true);  % Assign callback

            % Create UIAxes
            app.UIAxes = uiaxes(app.RealTimePlotAppUIFigure);
            app.UIAxes.Position = [0 185 580 550];
            title(app.UIAxes, 'Shape Optimization Result','FontSize',16);
            xlabel(app.UIAxes, 'x (cm)', 'FontSize', 14)
            ylabel(app.UIAxes, 'y (cm)', 'FontSize', 14)
            zlabel(app.UIAxes, 'z (cm)', 'FontSize', 14)
            view(app.UIAxes, [0.3 -1 0.7]);
            axis(app.UIAxes, 'equal');
            grid(app.UIAxes,'on');
            evalc('colors = colormap(app.UIAxes,slanCM(''twilight''));');
            cb = colorbar(app.UIAxes);caxis(app.UIAxes,[-4.5 4.5]);cb.Position(1) = cb.Position(1) +0.05;
            yl = ylabel(cb,'Power (dB)','FontSize',10,'Rotation',270);yl.Position(1) = yl.Position(1)+1;

            % Create UITextArea
            app.UITextArea = uitextarea(app.RealTimePlotAppUIFigure);
            app.UITextArea.Position = [20 20 400 90];
            app.UITextArea.Editable = 'off';  % Make it read-only
            app.UITextArea.FontSize = 14;
            
            % Start logging
            startLogging(app);

            % Create and start a timer to periodically update the UITextArea
            app.UpdateTimer = timer('ExecutionMode', 'fixedRate', 'Period', 1, ...
                                    'TimerFcn', @(~,~) updateTextArea(app));
            start(app.UpdateTimer);

            hold(app.UIAxes, 'on');
            grid(app.UIAxes, 'on');
            % 1-4: peripheral
            % 5-18: xy lines
            for k = 1:4
                app.hPlot(k) = plot3(app.UIAxes, 0,0,0,'k-','LineWidth',2);
            end
            for k = 1:app.N_lines_per_axis*2
                app.hPlot(k+4) = plot3(app.UIAxes, 0,0,0,'k-','LineWidth',1.25);
            end
            app.hPlot(end+1) = plot3(app.UIAxes, 100,100,100,'r.','MarkerSize',0.1);
            app.UIAxes.XLim = app.window_width;
            app.UIAxes.YLim = app.window_width;
            app.UIAxes.ZLim = app.window_width;

            startMeasurement(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RealTimePlotAppUIFigure = uifigure('Visible', 'off');
            app.RealTimePlotAppUIFigure.Name = 'Real-Time Plot App';
            % Show the figure after all components are created
            app.RealTimePlotAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = shape_stress_reconstruction

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RealTimePlotAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @name)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RealTimePlotAppUIFigure)
        end
    end
end